classdef airborne_prop_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        AircraftParametersPanel        matlab.ui.container.Panel
        lbfLabel                       matlab.ui.control.Label
        TWRatioEditField               matlab.ui.control.NumericEditField
        TWRatioEditFieldLabel          matlab.ui.control.Label
        MaxTakeoffWeightEditField      matlab.ui.control.NumericEditField
        MaxTakeoffWeightLabel          matlab.ui.control.Label
        BatteryParametersPanel         matlab.ui.container.Panel
        mAhLabel                       matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        BatteryCapacityEditField       matlab.ui.control.NumericEditField
        BatteryCapacityEditFieldLabel  matlab.ui.control.Label
        CellVoltageEditField           matlab.ui.control.NumericEditField
        CellVoltageEditFieldLabel      matlab.ui.control.Label
        NumberofCellsEditField         matlab.ui.control.NumericEditField
        NumberofCellsEditFieldLabel    matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        RightPanel                     matlab.ui.container.Panel
        PropSearchParametersPanel      matlab.ui.container.Panel
        TipMachEditField               matlab.ui.control.NumericEditField
        TipMachEditFieldLabel          matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        UpperLabel                     matlab.ui.control.Label
        LowerLabel                     matlab.ui.control.Label
        ALabel                         matlab.ui.control.Label
        CurrentEditFieldLabel          matlab.ui.control.Label
        Current_lower                  matlab.ui.control.NumericEditField
        Current_upper                  matlab.ui.control.NumericEditField
        KV_upper                       matlab.ui.control.NumericEditField
        KV_lower                       matlab.ui.control.NumericEditField
        KVEditFieldLabel               matlab.ui.control.Label
        lbflabel2                      matlab.ui.control.Label
        ThrustRangeEditField           matlab.ui.control.NumericEditField
        ThrustRangeEditFieldLabel      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        TW_ratio % Description
        N_cells
        cell_voltage
        capacity
        MTOW
        R_motor
        shaft_eff
        tip_mach
        thrust_range
        kv_lower
        kv_upper
        current_lower
        current_upper
    end
    
    properties (Access = public)
        prop_table % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function calculate(app, event)
            desired_thrust = app.TW_ratio .* app.MTOW;
            total_voltage_nominal = app.N_cells .* app.cell_voltage;
            app.prop_table = static_performance_search(app.tip_mach, desired_thrust, app.thrust_range, app.kv_lower, app.kv_upper, app.current_lower, app.current_upper, total_voltage_nominal);
        end

        % Value changed function: NumberofCellsEditField
        function NumberofCellsEditFieldValueChanged(app, event)
            app.N_cells = app.NumberofCellsEditField.Value;
            
        end

        % Value changed function: CellVoltageEditField
        function CellVoltageEditFieldValueChanged(app, event)
            app.cell_voltage = app.CellVoltageEditField.Value;
            
        end

        % Value changed function: BatteryCapacityEditField
        function BatteryCapacityEditFieldValueChanged(app, event)
            app.capacity = app.BatteryCapacityEditField.Value;
            
        end

        % Value changed function: MaxTakeoffWeightEditField
        function MaxTakeoffWeightEditFieldValueChanged(app, event)
            app.MTOW = app.MaxTakeoffWeightEditField.Value;
            
        end

        % Value changed function: TWRatioEditField
        function TWRatioEditFieldValueChanged(app, event)
            app.TW_ratio = app.TWRatioEditField.Value;
            
        end

        % Callback function
        function MotorResistanceEditFieldValueChanged(app, event)
            app.R_motor = app.MotorResistanceEditField.Value;
            
        end

        % Callback function
        function ShaftEfficiencyEditFieldValueChanged(app, event)
            app.shaft_eff = app.ShaftEfficiencyEditField.Value;
            
        end

        % Callback function
        function TipMachDropDownValueChanged(app, event)
            app.tip_mach = app.TipMachDropDown.Value;
            
        end

        % Value changed function: ThrustRangeEditField
        function ThrustRangeEditFieldValueChanged(app, event)
            app.thrust_range = app.ThrustRangeEditField.Value;
            
        end

        % Value changed function: KV_lower
        function KV_lowerValueChanged(app, event)
            app.kv_lower = app.KV_lower.Value;
            
        end

        % Value changed function: KV_upper
        function KV_upperValueChanged(app, event)
            app.kv_upper = app.KV_upper.Value;
            
        end

        % Value changed function: Current_lower
        function Current_lowerValueChanged(app, event)
            app.current_lower = app.Current_lower.Value;
            
        end

        % Value changed function: Current_upper
        function Current_upperValueChanged(app, event)
            app.current_upper = app.Current_upper.Value;
            
        end

        % Value changed function: TipMachEditField
        function TipMachEditFieldValueChanged(app, event)
            app.tip_mach = app.TipMachEditField.Value;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
%             newDisplayData = app.UITable.DisplayData;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BatteryParametersPanel = uipanel(app.LeftPanel);
            app.BatteryParametersPanel.Title = 'Battery Parameters';
            app.NumberofCellsEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.NumberofCellsEditFieldLabel.Text = 'Number of Cells';
            app.NumberofCellsEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.NumberofCellsEditField.Limits = [0 Inf];
            app.NumberofCellsEditField.RoundFractionalValues = 'on';
            app.NumberofCellsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofCellsEditFieldValueChanged, true);
            app.CellVoltageEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.CellVoltageEditFieldLabel.Text = 'Cell Voltage';
            app.CellVoltageEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.CellVoltageEditField.Limits = [0 Inf];
            app.CellVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @CellVoltageEditFieldValueChanged, true);
            app.BatteryCapacityEditFieldLabel = uilabel(app.BatteryParametersPanel);
            app.BatteryCapacityEditFieldLabel.Text = 'Battery Capacity';
            app.BatteryCapacityEditField = uieditfield(app.BatteryParametersPanel, 'numeric');
            app.BatteryCapacityEditField.Limits = [0 Inf];
            app.BatteryCapacityEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryCapacityEditFieldValueChanged, true);
            app.VLabel = uilabel(app.BatteryParametersPanel);
            app.VLabel.Text = 'V';
            app.mAhLabel = uilabel(app.BatteryParametersPanel);
            app.mAhLabel.Text = 'mAh';
            app.AircraftParametersPanel = uipanel(app.LeftPanel);
            app.AircraftParametersPanel.Title = 'Aircraft Parameters';
            app.MaxTakeoffWeightLabel = uilabel(app.AircraftParametersPanel);
            app.MaxTakeoffWeightLabel.Text = {'Max Takeoff'; 'Weight'};
            app.MaxTakeoffWeightEditField = uieditfield(app.AircraftParametersPanel, 'numeric');
            app.MaxTakeoffWeightEditField.Limits = [0 Inf];
            app.MaxTakeoffWeightEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTakeoffWeightEditFieldValueChanged, true);
            app.TWRatioEditFieldLabel = uilabel(app.AircraftParametersPanel);
            app.TWRatioEditFieldLabel.Text = 'T/W Ratio';
            app.TWRatioEditField = uieditfield(app.AircraftParametersPanel, 'numeric');
            app.TWRatioEditField.Limits = [0 Inf];
            app.TWRatioEditField.ValueChangedFcn = createCallbackFcn(app, @TWRatioEditFieldValueChanged, true);
            app.lbfLabel = uilabel(app.AircraftParametersPanel);
            app.lbfLabel.Text = 'lbf';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.PropSearchParametersPanel = uipanel(app.RightPanel);
            app.PropSearchParametersPanel.Title = 'Prop Search Parameters';
            app.ThrustRangeEditFieldLabel = uilabel(app.PropSearchParametersPanel);
            app.ThrustRangeEditFieldLabel.Text = 'Thrust Range';
            app.ThrustRangeEditField = uieditfield(app.PropSearchParametersPanel, 'numeric');
            app.ThrustRangeEditField.Limits = [0 Inf];
            app.ThrustRangeEditField.ValueChangedFcn = createCallbackFcn(app, @ThrustRangeEditFieldValueChanged, true);
            app.lbflabel2 = uilabel(app.PropSearchParametersPanel);
            app.lbflabel2.Text = 'lbf';
            app.KVEditFieldLabel = uilabel(app.PropSearchParametersPanel);
            app.KVEditFieldLabel.Text = 'KV';
            app.KV_lower = uieditfield(app.PropSearchParametersPanel, 'numeric');
            app.KV_lower.Limits = [0 Inf];
            app.KV_lower.ValueChangedFcn = createCallbackFcn(app, @KV_lowerValueChanged, true);
            app.KV_upper = uieditfield(app.PropSearchParametersPanel, 'numeric');
            app.KV_upper.Limits = [0 Inf];
            app.KV_upper.ValueChangedFcn = createCallbackFcn(app, @KV_upperValueChanged, true);
            app.KV_upper.Value = Inf;
            app.Current_upper = uieditfield(app.PropSearchParametersPanel, 'numeric');
            app.Current_upper.Limits = [0 Inf];
            app.Current_upper.ValueChangedFcn = createCallbackFcn(app, @Current_upperValueChanged, true);
            app.Current_upper.Value = Inf;
            app.Current_lower = uieditfield(app.PropSearchParametersPanel, 'numeric');
            app.Current_lower.Limits = [0 Inf];
            app.Current_lower.ValueChangedFcn = createCallbackFcn(app, @Current_lowerValueChanged, true);
            app.CurrentEditFieldLabel = uilabel(app.PropSearchParametersPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.ALabel = uilabel(app.PropSearchParametersPanel);
            app.ALabel.Text = 'A';
            app.LowerLabel = uilabel(app.PropSearchParametersPanel);
            app.LowerLabel.Text = 'Lower';
            app.UpperLabel = uilabel(app.PropSearchParametersPanel);
            app.UpperLabel.Text = 'Upper';
            app.CalculateButton = uibutton(app.PropSearchParametersPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.CalculateButton.Text = 'Calculate';
            app.TipMachEditFieldLabel = uilabel(app.PropSearchParametersPanel);
            app.TipMachEditFieldLabel.Text = 'Tip Mach';
            app.TipMachEditField = uieditfield(app.PropSearchParametersPanel, 'numeric');
            app.TipMachEditField.Limits = [0 Inf];
            app.TipMachEditField.ValueChangedFcn = createCallbackFcn(app, @TipMachEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = airborne_prop_selection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end